classdef Slider_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        archivo         matlab.ui.container.Menu
        grabar          matlab.ui.container.Menu
        natural         matlab.ui.container.Menu
        personalizado   matlab.ui.container.Menu
        Untitled_1      matlab.ui.container.Menu
        Untitled_2      matlab.ui.container.Menu
        nosotros        matlab.ui.container.Menu
        universidad     matlab.ui.container.Menu
        uipanel1        matlab.ui.container.Panel
        axes1           matlab.ui.control.UIAxes
        pushbutton1     matlab.ui.control.Button
        stop            matlab.ui.control.Button
        edit1           matlab.ui.control.EditField
        text3           matlab.ui.control.Label
        text4           matlab.ui.control.Label
        text5           matlab.ui.control.Label
        slider2         matlab.ui.control.Slider
        text8           matlab.ui.control.Label
        slider7         matlab.ui.control.Slider
        text11          matlab.ui.control.Label
        popupmenu3      matlab.ui.control.DropDown
        slider10        matlab.ui.control.Slider
        pushbutton5     matlab.ui.control.Button
        pushbutton6     matlab.ui.control.Button
        text13          matlab.ui.control.Label
        ejey            matlab.ui.control.Slider
        ejex            matlab.ui.control.Slider
        text14          matlab.ui.control.Label
        text15          matlab.ui.control.Label
        uibuttongroup3  matlab.ui.container.ButtonGroup
        radiobutton12   matlab.ui.control.RadioButton
        radiobutton11   matlab.ui.control.RadioButton
        radiobutton23   matlab.ui.control.RadioButton
        pushbutton9     matlab.ui.control.Button
        pushbutton10    matlab.ui.control.Button
        uitable10       matlab.ui.control.Table
        uibuttongroup4  matlab.ui.container.ButtonGroup
        original        matlab.ui.control.RadioButton
        deseado         matlab.ui.control.RadioButton
        slider15        matlab.ui.control.Slider
        slider16        matlab.ui.control.Slider
        text21          matlab.ui.control.Label
        cabezano        matlab.ui.control.Button
        popupmenu6      matlab.ui.control.DropDown
        text22          matlab.ui.control.Label
        pushbutton16    matlab.ui.control.Button
        uibuttongroup6  matlab.ui.container.ButtonGroup
        radiobutton26   matlab.ui.control.RadioButton
        radiobutton27   matlab.ui.control.RadioButton
        popupmenu4      matlab.ui.control.DropDown
        popupmenu7      matlab.ui.control.DropDown
        pushbutton17    matlab.ui.control.Button
        edit12          matlab.ui.control.EditField
        text25          matlab.ui.control.Label
        edit13          matlab.ui.control.EditField
        edit14          matlab.ui.control.EditField
        text26          matlab.ui.control.Label
        text27          matlab.ui.control.Label
        text28          matlab.ui.control.Label
        salirzoom       matlab.ui.control.Button
        cabeza          matlab.ui.control.Button
    end

    
    methods (Access = private)
        function x = RK4(app, x0, u, dt, fun)
            global np ndim
            x=zeros(2*np,ndim);
            
               p1=feval(fun,x0,u);
               p2=feval(fun,x0+(dt/2)*p1,u);
               p3=feval(fun,x0+(dt/2)*p2,u);
               p4=feval(fun,x0+(dt)*p3,u);
               x=x0+dt*((1/6)*p1+(1/3)*p2+(1/3)*p3+(1/6)*p4);
        end
        
        function T_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to T (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            
            % set(app.T,'Min',0,'Max',1,'Value',0,'SliderStep',[0.01 0.1])
            % tiempo=get(app.T,'Value')
            % tiempo
            
            end
        end
        
        function x = csdynmatlab(app, t, xinitt)
            global np ndim beta pp flag alpha zdes M K voldvi xnodevi aoldxvi aoldyvi An str1
            global deseado original str2 distance pow str3 str4 sigma
            
            %influenceu=@(distance,pow)distance^5./((1+distance.^2).^pow);
            %influenceu=@(distance,pow)0;
            
            if original == 1
               influenceu = str1;
               influence = str2;
            end
            
            if deseado == 1
               influenceu=str1;
               influence = @(distance,pow)eval(str2{1});
            end
            xinit=reshape(xinitt,2*np,ndim);
            a=zeros(np,np,ndim);
            a2=zeros(np,np,ndim);
            f=zeros(np,np,ndim);
            distance=zeros(np,np);
            difv=zeros(np,np,ndim);
            difx=zeros(np,np,ndim);
            Np=np;
            x0=xinit(1:np,:);
            v0=xinit(np+1:2*np,:);
            mdiag=-eye(np);
            front=[[zeros(1,np-1);eye(np-1)] zeros(np,1)];
            back=[zeros(np,1) [eye(np-1);zeros(1,np-1)]];
            
            for i=1:np
                distance(i,:)=sqrt(sum((repmat(x0(i,:),Np,1)-x0).^2,2));
                difv(i,:,:)=v0-repmat(v0(i,:),Np,1);
                difx(i,:,:)=-(x0-repmat(x0(i,:),Np,1));
            end
            distform=[];
            for i=1:np-1
                %distance from desired formation
                distform(i)=norm(x0(i,:)-x0(i+1,:)-zdes(i,:),2);
            end
            
            distance=distance+eye(length(distance)); %this is to avoid division by zero
            alignment=1*sqrt((0.5/np)*(sum(sum(difv(:,:,1).^2))+sum(sum(difv(:,:,2).^2))));


            for k=1:ndim;
                a(:,:,k)=K*influence(distance,alpha).*difv(:,:,k); %full observation influence
                f(:,:,k)=repulsion(distance,pp).*difx(:,:,k);
            end
            
            u(1,:)=-M*influenceu(distform(1),beta)*(x0(1,:)-x0(2,:)-zdes(1,:));
            u(Np,:)=M*influenceu(distform(Np-1),beta)*(x0(Np-1,:)-x0(Np,:)-zdes(Np-1,:));
            for i=2:Np-1
                u(i,:)=M*influenceu(distform(i-1),beta)*(x0(i-1,:)-x0(i,:)-zdes(i-1,:))-M*influenceu(distform(i),beta)*(x0(i,:)-x0(i+1,:)-zdes(i,:));
            end


            %zdes=[z1 z2];
            %     temp=diag(distform,1);
            %     temp2=diag(distform,-1);
            %     distform=distform-diag(temp,1)+diag(temp2,1);
            %     for k=1:ndim;
            %         au(:,:,k)=influenceu(distform,beta).*(-difx(:,:,k)+(back-front)*zdes(:,k)).*(back+front);
            %     end
            %     u=squeeze(sum(au,2));
            
            % (1/np)*
            x(1:np,:)=v0;
            x(np+1:2*np,:)=squeeze(sum(a,2))./Np+0*alignment*squeeze(sum(f,2))+1*u;
            x=x(:);
        end
        
        function x = csdynshape(app, xinit, u)
            global np ndim beta pp flag distance pow alpha str4 zdes M K sigma str1 original deseado str2
            
            if original == 1
               influenceu = str1;
               influence = str2;
            end
            
            if deseado == 1
               influenceu=str1;
            %    influence = @(distance,pow)eval(str2{1});
                influence = str4;
            end
            
            flag2=0;
            if u==1
                flag2=1;
            end
            u=zeros(np,ndim);
            
            a=zeros(np,np,ndim);
            f=zeros(np,np,ndim);
            distance=zeros(np,np);
            difv=zeros(np,np,ndim);
            difx=zeros(np,np,ndim);
            Np=np;
            x0=xinit(1:np,:);
            v0=xinit(np+1:2*np,:);
            mdiag=-eye(np);
            front=[[zeros(1,np-1);eye(np-1)] zeros(np,1)];
            back=[zeros(np,1) [eye(np-1);zeros(1,np-1)]];
            
            for i=1:np
                distance(i,:)=sqrt(sum((repmat(x0(i,:),Np,1)-x0).^2,2));
                difv(i,:,:)=v0-repmat(v0(i,:),Np,1);
                difx(i,:,:)=-(x0-repmat(x0(i,:),Np,1));
            end
            distform=[];
            for i=1:np-1
                %distance from desired formation
                distform(i)=norm(x0(i,:)-x0(i+1,:)-zdes(i,:),2);
            end
            
            distance=distance+eye(length(distance)); %this is to avoid division by zero
            alignment=1*sqrt((0.5/np)*(sum(sum(difv(:,:,1).^2))+sum(sum(difv(:,:,2).^2))));
            for k=1:ndim;
                a(:,:,k)=K*influence(distance,alpha).*difv(:,:,k);
                f(:,:,k)=repulsion(distance,pp).*difx(:,:,k);
            end
            
            u(1,:)=-M*influenceu(distform(1),beta)*(x0(1,:)-x0(2,:)-zdes(1,:));
            u(Np,:)=M*influenceu(distform(Np-1),beta)*(x0(Np-1,:)-x0(Np,:)-zdes(Np-1,:));
            for i=2:Np-1
                u(i,:)=M*influenceu(distform(i-1),beta)*(x0(i-1,:)-x0(i,:)-zdes(i-1,:))-M*influenceu(distform(i),beta)*(x0(i,:)-x0(i+1,:)-zdes(i,:));
            end
            
            %zdes=[z1 z2];
            %     temp=diag(distform,1);
            %     temp2=diag(distform,-1);
            %     distform=distform-diag(temp,1)+diag(temp2,1);
            %     for k=1:ndim;
            %         au(:,:,k)=influenceu(distform,beta).*(-difx(:,:,k)+(back-front)*zdes(:,k)).*(back+front);
            %     end
            %     u=squeeze(sum(au,2));
            
            % (1/np)*
            if flag2
                u=u+[50 0;zeros(np-1,2)];
            end
            x(1:np,:)=v0;
            x(np+1:2*np,:)=squeeze(sum(a,2))./np+0*alignment*squeeze(sum(f,2))+1*u;
        end
        
        function edit10_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit11_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit15_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit15 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit16_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit16 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit2_CreateFcn(app, hObject, eventdata, handles)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit7_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit8_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit9_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function menu_CreateFcn(app, hObject, eventdata, handles)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function pausa_Callback(app, hObject, eventdata, handles)
            p=get(app.pausa,'Value');
        end
        
        function popupmenu8_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function pushbutton11_Callback(app, hObject, eventdata, handles)
            % hObject    handle to pushbutton11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.pushbutton11,'String',"Ok!")
        end
        
        function f = repulsion(app, distance, pow)
            
            f=1./(distance-1e-10).^pow;
        end
        
        function slider3_CreateFcn(app, hObject, eventdata, handles)
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider8_CreateFcn(app, hObject, eventdata, handles)
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider9_CreateFcn(app, hObject, eventdata, handles)
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function Slider_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to Slider (see VARARGIN)
%             
%             % Choose default command line output for Slider
%             app.output = hObject;
%             % Update handles structure
%             guidata(hObject, handles);
%              global vectoresAgentes vector;
%              vectoresAgentes = [];
%             
%              vector = 1;
        end

        % Button pushed function: cabeza
        function cabeza_Callback(app, event)
            set(app.cabeza,'String','ok');
        end

        % Button pushed function: cabezano
        function cabezano_Callback(app, event)
            set(app.cabeza,'String','Con cabeza');
        end

        % Menu selected function: nosotros
        function nosotros_Callback(app, event)
            winopen('Nosotros.docx');
        end

        % Menu selected function: personalizado
        function personalizado_Callback(app, event)
            set(app.uitable10,'Visible','on');
        end

        % Value changed function: popupmenu3
        function popupmenu3_Callback(app, event)
            m=get(app.popupmenu3,'Value');
        end

        % Value changed function: popupmenu4
        function popupmenu4_Callback(app, event)
            %%Menu desplegable para figuras de agentes %%%%
            global cuadrado bandada poligon elegir circulo triangulo creativo
            elegir = 0;
            cuadrado = 0;
            bandada = 0;
            poligon = 0;
            circulo = 0;
            triangulo = 0;
            creativo = 0;
            v=get(app.popupmenu4,'Value');
            %bandada = get(app.popupmenu4,'Value');
            
            switch v
            
            
                case 2
                    cuadrado = 1;
                case 3
                    bandada = 1;
                case 4
                    poligon = 1;
                case 5
                    circulo = 1;
                case 6
                    triangulo = 1;
                case 7
                    creativo = 1;
                    set(app.text28,'Visible','on')
                    set(app.edit12,'Visible','on')
                    set(app.edit13,'Visible','on')
                    set(app.edit14,'Visible','off')
                    set(app.text25,'Visible','on')
                    set(app.text26,'Visible','on')
                    set(app.text25,'String','x')
                    set(app.text26,'String','y')
                    set(app.text27,'Visible','off')
                    set(app.text28,'String','Ingrese Vectores')
                    set(app.pushbutton17,'Visible','on')
                otherwise
            
                elegir = 1;
            
            end
        end

        % Value changed function: popupmenu7
        function popupmenu7_Callback(app, event)
            global cuadrado paredes circulo elegir bandada poligon olympic piramide creativo
            elegir = 0;
            cuadrado = 0;
            paredes = 0;
            circulo = 0;
            bandada = 0;
            olympic = 0;
            piramide = 0;
            creativo = 0;
            v=get(app.popupmenu7,'Value');
            %bandada = get(app.popupmenu4,'Value');
            
            switch v
            
            
                case 2
                    cuadrado = 1;
                case 3
                    paredes = 1;
                case 4
                    circulo = 1;
                case 5
                    piramide=1;
                case 6
                    bandada=1;
                case 7
                    poligon=1;
                case 8
                    olympic=1;
                case 9
                    creativo=1;
                    set(app.text28,'Visible','on')
                    set(app.edit12,'Visible','on')
                    set(app.edit13,'Visible','on')
                    set(app.edit14,'Visible','on')
                    set(app.text25,'Visible','on')
                    set(app.text26,'Visible','on')
                    set(app.text27,'Visible','on')
                    set(app.text25,'String','x')
                    set(app.text26,'String','y')
                    set(app.text27,'String','z')
                    set(app.text28,'String','Ingrese Vectores')
                    set(app.pushbutton17,'Visible','on')
            
                otherwise
            
                elegir = 1;
            
            end
        end

        % Button pushed function: pushbutton10
        function pushbutton10_Callback(app, event)
            set(app.pushbutton10,'String',"Centrado")
        end

        % Button pushed function: pushbutton17
        function pushbutton17_Callback(app, event)
            % hObject    handle to pushbutton17 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global vectoresAgentes  vector;
            
            vectorx = get(app.edit12,'String');
            vectory = get(app.edit13,'String');
            vectorz = get(app.edit14,'String');
            
            cont = 3;
            dim = get(app.radiobutton26,'Value');
            if dim == 1
                cont = 2;
            end
            
            vectorx;
            vectory;
            vectorz;
            
            vectoresAgentes (vector,1)= [str2double(vectorx)];
            set(app.text25,'String',vectoresAgentes(vector,1));
            set(app.pushbutton17,'Value',0);
            
            vectoresAgentes (vector,2)= [str2double(vectory)];
            set(app.text26,'String',vectoresAgentes(vector,2));
            set(app.pushbutton17,'Value',0);
            
            if cont == 3
            vectoresAgentes (vector,3)= [str2double(vectorz)];
            set(app.text27,'String',vectoresAgentes(vector,3));
            set(app.pushbutton17,'Value',0);
            end
            vectoresAgentes;
            vector ;
            set(app.text28,'String',"Ingresado Vector "+vector)
            
            vector=vector+1;
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            
             global np ndim tf sigma K beta pp flag alpha M zdes cuadrado bandada poligon elegir
             global paredes str2 distance pow circulo str3 str4 triangulo piramide olympic dt str1 original deseado creativo vectoresAgentes vector
             % reestablece nombre boton stop
            % clear all
            % clc
            set(app.popupmenu6,'Visible','on')
            set(app.text22,'Visible','on')
            set(app.salirzoom,'String',"Salir zoom")
            
            vectoresAgentes;
            tamanio = size(vectoresAgentes);
            fila = tamanio(1,1);
            columna =tamanio(1,2);
            restr = tamanio(1,1);
            conta = [];
            conta2 = [];
            conta3 = [];
            contador = 0;
            for i=1:fila
                conta = vectoresAgentes(i,:);
                for j=1:columna
                    conta2 = conta(1,j);
            %         conta3 = conta(j,1)
                    if isnan(conta2)
                        contador = 1;
                    end
                end
            end
            % for i=1:columna;
            %     conta2 = vectoresAgentes(:,i)
            % end
            
            % str1=get(app.edit11,'String');
            
            set(app.pushbutton16, 'BackgroundColor' , 'yellow' );
            set(app.stop,'String',"Stop")
            
            npp    = get(app.edit1,'String');%Se guarda el vaor que se ingrese en el cuadro de texto en guide en la variable "npp"
            nppp   = str2double(npp); %el valor de npp se convierte en un numer
            
            if nppp <= 1
                set(app.text8,'String','Ingrese valor mayor a 1')
                set (app.pushbutton16, 'BackgroundColor' , 'red' )
                set(app.stop,'String','Stop.')
            end
            if isnan (nppp)
                set(app.text8,'String','Ingrese valor numerico')
                set (app.pushbutton16, 'BackgroundColor' , 'red' )
                set(app.stop,'String','Stop.')
            end
            
            if (tamanio(1,1)~= (nppp-1)|| contador ==1 ) && (get(app.popupmenu7,'Value')== 9 || get(app.popupmenu4,'Value') == 7)
                vectoresAgentes = [];
                vector = 1;
                tamanio = [];
                restr = 0;
                set(app.text8,'String','Ingrese la cantidad correcta de vectores')
                set (app.pushbutton16, 'BackgroundColor' , 'red' )
                set(app.stop,'String','Stop.')
            
            else
            
                set(app.text8,'String',' ')


                %%Codigo para ingresar cantidad de agentes
            dos = get(app.radiobutton26,'Value');
            tres= get(app.radiobutton27,'Value');


            if dos ==1;


            a=get(gca,'xlim');b=get(gca,'ylim');c=get(gca,'zlim');
            axis([a b])
            a=a+[-2 2];b=b+[-2 2];
            
            flagcolision=0;
            tic
            original= get(app.original,'Value');
            deseado= get(app.deseado,'Value');
            
            
            if original == 1
            str1=@(distance,pow)1./((1+distance.^2).^pow);
            
            if distance == 0
            
                str2 = @(distance,pow)0;
            else
            str2 =@(distance,pow)1./((sigma+distance).^pow);
            
            end
            
            slider = 20000;
            slider2 = 100;
            slider3 = 100;
            slider4 = 100;
              set(app.slider2,'MIN',0,'MAX',slider);
              set(app.slider7,'MIN',0,'MAX',slider);
              set(app.slider10,'MIN',1,'MAX',slider4);
              set(app.ejey,'MIN',-slider2,'MAX',slider2);
              set(app.ejex,'MIN',-slider3,'MAX',slider3);
            flag=0;
            M=50;
            %sigma=0 singular influence
            sigma=0;
            K=100;
            beta=0.4;
            alpha=1.1;
            tf=20;
            pp=2; %potencia a la que esta elevada
            % np=5; %number of agents
            dt=0.001;
            
            end
            %OPCION DE PERSONALIZADO
            if deseado == 1
              recibido=get(app.uitable10,'data');
            
            infl = recibido(12,2);
            str1 = @(distance,pow)eval(infl{1});
            
            
            if distance == 0
            
                str4 = @(distance,pow)0;
            else
            str2 = recibido(11,2);
            % str3 = eval(str2{1});
            str4 = @(distance,pow)eval(str2{1});
            
            end
            
            
            %valores de la tabla
              v1 = recibido(1,2);
              alpha = str2double(v1);
              v2 = recibido(2,2);
              beta = str2double(v2);
              v3 = recibido(3,2);
              sigma = str2double(v3);
              v4 = recibido(4,2);
              K = str2double(v4);
              v5 = recibido(5,2);
              pp = str2double(v5);
              v6 = recibido(6,2);
              flag = str2double(v6);
              v7 = recibido(7,2);
              M = str2double(v7);
              v7 = recibido(8,2);
              tf = str2double(v7);
              v8 = recibido(9,2);
              dt = str2double(v8);
              v9 = recibido(13,2);
              slider = str2double(v9);
              v10 = recibido(14,2);
              slider2 = str2double(v10);
              v11 = recibido(15,2);
              slider3 = str2double(v11);
              v12 = recibido(16,2);
              slider4 = str2double(v12);
            
              set(app.slider2,'MIN',0,'MAX',slider);
              set(app.slider7,'MIN',0,'MAX',slider);
              set(app.slider10,'MIN',1,'MAX',slider4);
              set(app.ejey,'MIN',-slider2,'MAX',slider2);
              set(app.ejex,'MIN',-slider3,'MAX',slider3);
            end


            npp    = get(app.edit1,'String');%Se guarda el vaor que se ingrese en el cuadro de texto en guide en la variable "npp"
            nppp   = str2double(npp); %el valor de npp se convierte en un numer
            if nppp <= 1
                set(app.text8,'String','Ingrese valor mayor a 1')
                set (app.pushbutton16, 'BackgroundColor' , 'red' )
                set(app.stop,'String','Stop.')
            elseif isnan (nppp)
                set(app.text8,'String','Ingrese valor numerico')
                set (app.pushbutton16, 'BackgroundColor' , 'red' )
            
            
            else
            
            % set(app.text8,'String',' ')
            %%%%%%%
            % close all
            % clc
            %function [value,th]=consensus3(gamma0,lambda0)
            set(app.text8,'String','')
            set(app.radiobutton11,'Value',0)
            set(app.slider10,'Value',50)
            set(app.pushbutton5,'String','Play')
            set(app.pushbutton6,'String','Zoom Personal')
            set(app.ejey,'Value',0)
            set(app.ejex,'Value',0)
            set(app.pushbutton9,'visible','on')
            movimiento = 0;%Movimiento automatico de los agentes mediante boton
            
            % datos = get(handles,uitable3,'Data');
            % datos(:,:) = [];
            % set(app.uitable3,'Data',datos)


            ndim= 2;
            np=nppp;
            alpha1=10;
            ti=0;
            t=ti:dt:tf;
            nsteps=length(t);
            
            gamma0=14;
            lambda0=42.3;
            
            x00=zeros(np,ndim);
            v00=zeros(np,ndim);
            
            for i=1:np
                for kk=1:ndim
                    x00(i,kk)=10*rand-1;
                    v00(i,kk)=2*rand-1;
                end
            end
            vbar0=1/np*sum(v00,1);
            vbar2=1/np*sum(v00,1);
            zdes=zeros(np-1,ndim);
            
            %%%% en caso de elegir la primera opcion de popmenu %%%
            set (app.pushbutton16, 'BackgroundColor' , 'yellow' )
            if elegir == 1;
                set(app.text8,'String','Elegir forma de agente')
            
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if triangulo == 1
            
            %     val1=0;
            %     val2=5;
            %     val3 = floor(np/2);
            %     val4= vbar2(2), vbar2(1);
                zdes=[-0.5 -0.5;-0.5 -0.5;0 -0.5;0 -0.5;2 0;0 0.5;0 0.5;-0.5 0.5;-2.5 -1.5;-0.5 0;-0.5 0;-0.5 0;-0.5 0;1 0.5;0 0.5;0 0.5;0 0.5;-2 0;-1 0;-1 0;2 -0.5;0 -0.5;-1 0;-1 0;2 -0.5;0 -0.5;-1 0;-1 0;-2 0;0 0.5;0 0.5;0 0.5;0 0.5;-1 -1.5;-1 0.5;-1 -0.5;-1 -0.5;0 0.5;0 0.5;0 0.5;0 0.5;-1 -2];
            %     zdes=[0 0.5;-1 0;1 0.5;0 0.5;0 0.5;-2 -1;-1 -0.5;0 0.5;0 0.5;0 0.5;0 0.5;0.5 -1.75];
            end
            %%%%%%% CIRCULO2D %%%%%%%%%%%%%%%
            if circulo == 1
            conta=1;
            contador=0;
            zdess = 0;
            uno = 3;%1
            cero5 = 1;%0.5
            dif = 0;
            while conta < np
                if contador == 0
            %         zdes(conta,:) = [uno 0 -cero5];
                    zdes(conta,:) = [uno -cero5];
                    contador=+1;
                    conta=conta+1;
            
                elseif contador == 1
            %         zdes(conta,:) = [cero5 0 -uno];
                    zdes(conta,:) = [cero5 -uno];
                    conta=conta+1;
                    contador=contador+1;
                elseif contador == 2
            %         zdes(conta,:) = [-cero5 0 -uno];
                    zdes(conta,:) = [-cero5 -uno];
                    conta=conta+1;
                    contador=contador+1;
                elseif contador == 3
            %         zdes(conta,:) = [-uno 0 -cero5];
                    zdes(conta,:) = [-uno -cero5];
                    conta=conta+1;
                    contador=contador+1;
                elseif contador == 4
            %         zdes(conta,:) = [-uno 0 cero5];
                    zdes(conta,:) = [-uno cero5];
                    conta=conta+1;
                    contador=contador+1;
                elseif contador == 5
            %         zdes(conta,:) = [-cero5 0 uno];
                    zdes(conta,:) = [-cero5 uno];
                    conta=conta+1;
                    contador=contador+1;
                elseif contador == 6
            %         zdes(conta,:) = [cero5 0 uno];
                    zdes(conta,:) = [cero5 uno];
                    conta=conta+1;
                    contador=contador+1;
                else
                    zdess=+1;
                    zdes(conta,:) = [ -3  uno];
            %         zdes(conta,:) = [ -3 uno];
                    conta=conta+1;
                    contador = 0;
                    zdess = 0;
                    dif = dif +-3;
                end
            end
            end
            %%
            if creativo == 1
            
                zdes = [vectoresAgentes];
            end
            %%
            %%%%%%% bird-like flocking %%%%%%%%%%%%%%%
            if bandada == 1;
            vbardir=vbar0/norm(vbar0);
            anglevbdir=atan2(vbardir(2),vbardir(1));
            
            for i=1:floor(np/2)
                zdes(i,:)=-[2*cos(anglevbdir-10*2*pi/180) 2*sin(anglevbdir-10*2*pi/180)];
            end
            
            for i=floor(np/2)+1:np-1
                zdes(i,:)=[2*cos(anglevbdir+10*2*pi/180) 2*sin(anglevbdir+10*2*pi/180)];
            end
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%%%%%% any number of agents - regular poligon with agent at the center%%%
            set (app.pushbutton16, 'BackgroundColor' , 'yellow' )
            if poligon == 1;
            zdes=[];
            R=4;
            NN=np-1;
            theta1=2*pi/(NN);
            phi=2*pi*rand(1)/8;
            zdes(1,:)=-[R*cos(phi) R*sin(phi)];
            zdes(2,:)=-[R*cos(phi+theta1) R*sin(phi+theta1)]+[R*cos(phi) R*sin(phi)];
            
            for i=3:NN
                zdes(i,:)=[R*cos(phi+theta1*(i-2)) R*sin(phi+theta1*(i-2))]-[R*cos(phi+theta1*(i-1)) R*sin(phi+theta1*(i-1))];
            end
            end
            %%%%% any number of agents - square grid shape%%%%%%%%
            if cuadrado == 1;
            zdes=[];
            ss=2;
            ss1=0.5;
            NN=ceil(sqrt(np));
            kk=1;
            tt=0;
            flagg=1;
            while kk<=np-1
                if kk-tt<=NN
                    zdes(kk,:)=flagg*[0 ss];
                    kk=kk+1;
                    if kk-tt==NN&&kk~=np
                        zdes(kk,:)=[ss 0];
                        tt=tt+NN;
                        flagg=-flagg;
                        kk=kk+1;
            
                    end
                end
            end
            zdes
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %% Initial energies and gamma lambda
            Gamma=0;
            Lambda=0;
            Ec0=0;
            for i=1:np
                Ec0=Ec0+norm(v00(i,:),2).^2;
                for j=1:np
                    Gamma=Gamma+norm(x00(i,:)-x00(j,:),2).^2;
                    Lambda=Lambda+norm(v00(i,:)-v00(j,:),2).^2;
                end
            end
            
            Gamma=1/(2*np^2)*Gamma;
            Lambda=1/(2*np^2)*Lambda;
            %scaling to choose gamma lambda at t=0
            x00=sqrt(gamma0/Gamma)*x00;
            v00=sqrt(lambda0/Lambda)*v00;
            Gammap=0;
            Lambdap=0;
            Ec0p=0;
            D0=0;
            set (app.pushbutton16, 'BackgroundColor' , 'yellow' )
            for i=1:np
                Ec0p=Ec0p+norm(v00(i,:),2).^2; %kinetic
                for j=1:np
                    Gammap=Gammap+norm(x00(i,:)-x00(j,:),2).^2;
                    Lambdap=Lambdap+norm(v00(i,:)-v00(j,:),2).^2;
                if original == 1
                    D0=D0+0.5*(K/np)*str2(norm(x00(i,:)-x00(j,:),2),alpha)*norm(v00(i,:)-v00(j,:),2)^2;
                end
            
                if deseado == 1
                   D0=D0+0.5*(K/np)*str4(norm(x00(i,:)-x00(j,:),2),alpha)*norm(v00(i,:)-v00(j,:),2)^2;
                end
                end
            end
            Ep0=0;
            for i=2:np
                t=norm(x00(i-1,:)-x00(i,:)-zdes(i-1,:),2).^2;
                Ep0=Ep0+M*(sqrt(t+1)-sqrt(1)); %potential for beta=0.5
            end
            
            Gammap=1/(2*np^2)*Gammap;
            Lambdap=1/(2*np^2)*Lambdap;
            
            % % SQUARE
            % np=5;
            % % np=nppp;
            % modv=1;
            % side=2;
            
            % % SQUARE 2
            % x00(1,:)=[-side 0]; v00(1,:)=-modv*[1 0];
            % x00(2,:)=[0 side]; v00(2,:)=-modv*[-1. 0];
            % x00(3,:)=[side 0]; v00(3,:)=-modv*[0 -1];
            % x00(4,:)=[0 -side]; v00(4,:)=-modv*[0 1];
            %
            % zdes(1,:)=[side side];
            % zdes(2,:)=[side -side];
            % zdes(3,:)=[-side -side];
            
            x=zeros(2*np,ndim,nsteps);
            x(:,:,1)=[x00;v00];
            x0=x00;
            v0=v00;
            
            beta2=0 ;
            distance=zeros(np,np);
            u=zeros(np,ndim,nsteps-1);
            vbar=zeros(1,2);
            i=0;
            p=0.1;
            q=0.9;
            %typeofcontrol=5;
            Rad=0;
            theta=0;
            dd=NaN;
            for i=1:np
                for j=1:np
                    if i~=j
                        dd=min(norm(x0(i,:)-x0(j,:),2).^2,dd);
                    end
                end
            end
            set (app.pushbutton16, 'BackgroundColor' , 'yellow' )
            
            % recibido=get(app.uitable10,'data');
            % stringsolver = recibido(10,2);
            % solver = eval(stringsolver{1});
            
            %         if solver == "ode23"
                    DD(1)=dd;
                    % Theta(1)=theta;
                    Gamma(1)=gamma0;
                    Lambda(1)=lambda0;
            
            
                    VB(1,:)=vbar0;
            
                    %energy
                    Ec=zeros(1,length(0:dt:tf));
                    Ep=zeros(1,length(0:dt:tf));
                    Ec(1)=Ec0p/2;
                    Ep(1)=Ep0;
                    DD=zeros(1,length(0:dt:tf));
                    DD(1)=D0;
            
                    %ode23s%%%
                    xinitt=[x00(:,1);v00(:,1);x00(:,2);v00(:,2)];
                    [t,X]=ode23s(@csdynmatlab,0:dt:tf,xinitt); % resuelve el sistema de ecuaciones diferenciales con el solver "ode23"
                    X=X';
                    ftime=length(t);
                    x1=X(1:np,:);
                    x2=X(2*np+1:3*np,:);
                    v1=X(np+1:2*np,:); % desde np + 1 hasta 2np tenemos la velocidad en el ejex
                    v2=X(3*np+1:4*np,:);
                    %%%%%%%
            %         end
            
                  %  if solver == "RK4" || solver == "rk4"
                        %RK
            %             for i=1:nsteps-1
            %
            %                 %x(:,:,i+1)=RK4(x(:,:,i),u(:,:,i),dt,@cscdyn);
            %                 x(:,:,i+1)=RK4(x(:,:,i),u(:,:,i),dt,@csdynshape);
            %                 x0=squeeze(x(1:np,:,i+1));
            %                 v0=squeeze(x(np+1:end,:,i+1));
            %
            %                 %for i=1:np
            %                 %distform(i,:)=sqrt(sum((repmat(x0(i,:),np,1)-x0-repmat(zdes,np,1)).^2,2));
            % %               end
            %                 gamma=0;
            %                 lambda=0;
            %                 theta=0;
            %                 dd=NaN;
            %                 vbbb=0;
            %                 %lambda gamma theta and dd
            %                 for ii=1:np
            %                     for jj=1:np
            %                         gamma=gamma+norm(x0(ii,:)-x0(jj,:),2).^2;
            %                         lambda=lambda+norm(v0(ii,:)-v0(jj,:),2).^2;
            %                     end
            %                     if ii<np
            %                         %theta=theta+norm(x0(ii,:)-x0(ii+1,:)-zdes,2).^2;
            %                     end
            %                     if ii~=jj
            %                         dd=min(norm(x0(ii,:)-x0(jj,:),2).^2,dd);
            %                     end
            %
            %                 end
            %                 % Theta(i+1)=theta/(np-1);
            %                 Gamma(i+1)=1/(2*np^2)*gamma;
            %                 Lambda(i+1)=1/(2*np^2)*lambda;
            %                 %????
            %                 if dd<=1e-20
            %                 flagcolision=1;
            %                 end
            %                 DD(i+1)=dd;
            %                 VB(i+1,:)=1/np*sum(v0,1);
            %         %       tests to stop if consensus is likely or unlikely to be achieved (for
            %     %            bench)
            % %               if i>=501
            % %               test=sum(Lambda(i-150:i));
            % %               test2=sum(Theta(i-150:i));
            % %               if test<=1||test2>=1000
            % %               break
            % %               toc
            % %               end
            % %               end
            %
            %             end
            
                  %  end
            
                    col = jet(1);
                    hold on
                    d=1;
                    ip=1;
                    ipp=1;
                    dd=0;
                    sss=0;
            
                    n = 1;
                    movim = 1;
                    ss = 1;
                    zz = 1;
                    ejexx=1;
                    ejeyy=1;
             set (app.pushbutton16, 'BackgroundColor' , 'yellow' )
            
            
             [xx2,yy2,zz2] = size(x);
            xx2;
            yy2;
            zz2;
            if zz2 <= slider
            
            set(app.slider2,'MIN',0,'MAX',zz2);
            set(app.slider7,'MIN',0,'MAX',zz2);
            
            end
            
             while   dd==0
            
                   %Menu2 es para mostrar en una lista las posiciones de los agentes
            menu2 = [1:np];
                set(app.popupmenu6,'string',menu2)
            %Menu3 almacenara las posiciones espaciales de cada agente
            menu3=[];
            
                stop=get(app.stop,'String');
                if stop == "Stop."
                   dd=1;
                   continue
                end
            
            
                centrar = get(app.pushbutton10,'String');
                movimiento = get(app.pushbutton5,'String');
            
            
            % if movim == 20001
            %     movimiento = "no";
            % end
            
            if movim == 20001
                movimiento = "no";
                set(app.pushbutton5,"String","Reiniciar?");
                Reiniciar = get(app.pushbutton5,'String');
                while Reiniciar == "Reiniciar?"
                Reiniciar = get(app.pushbutton5,'String');
                pause(0.01)
                end
            
                set(app.slider2,'Value',1);
                movim = 1;
            
                Reinicia = get(app.pushbutton5,'String');
                while Reinicia == "Play"
                Reinicia = get(app.pushbutton5,'String');
                pause(0.01)
                end
            
            end
            
            %%% AQUI COMIENZA CODIGO PARA GENERAR MOVIMIENTO AUTOMATICO DE LOS AGENTES
            if movimiento == "Ok!"
                set(app.slider2,'Value',movim);
                movim = movim + 200;
                pause(0.1)
            end
            
                    s= get(app.slider2,'Value');
                    s2=get(app.slider7,'Value');
                    ss=round(s);%ss es la variable que se genera con el slider y se redondea
                    ss2=round(s2);
            
            
            if      ss==0;
                    ss=1;
            
            end
            
            if      ss2==0;
                    ss2=1;
            end
            
            if  sss~=ss | sss2~=ss2 | zz ~= get(app.slider10,'Value') | ejexx ~= get(app.ejex,'Value') | ejeyy ~= get(app.ejey,'Value') | centrar == "Centrado"
            %    if  d~=1
            %       if  ss~=sss
            %           ip=sss;
            %
            %       end
            %
            % if      ss2~=sss2
            %         ip=sss2;
            %
            % end
            %     end
                    cla%borra lo que se genera anteriormente de los plot, y asi no se sobre escriben y se optimiza
                    hold on
                    if centrar == "Centrado" % Realiza el centrado de los slider ejex y ejey
                        set(app.ejex,'Value',0)
                        set(app.ejey,'Value',0)
                        set(app.slider10,'Value',50)
                        set(app.pushbutton10,'String',"Centrar")
                    end
            
            
                     N=np;
                     col=jet(N);
             menu3=[];
             estela = get(app.estela,'Value');
            for     i=1:np
            
                      ejey = get(app.ejey,'Value');
                      ejex = get(app.ejex,'Value');


                    set (app.pushbutton16, 'BackgroundColor' , 'green' )
                    if estela == 0
                        plot(x1(i,ss2:1:ss),x2(i,ss2:1:ss),'LineWidth',2,'LineStyle','-')
                        plot(x1(i,ss),x2(i,ss),'Marker','o','MarkerSize',10,'MarkerFaceColor',col(i,:))
                        plot(x1(i,ss2),x2(i,ss2),'Marker','.','MarkerSize',14,'MarkerFaceColor',col(i,:))
                    end
            
                    if estela == 1
                        if ss<800
                            plot(x1(i,ss2:1:ss),x2(i,ss2:1:ss),'LineWidth',2,'LineStyle','-')
                        end
                        if ss > 800
                            plot(x1(i,ss-800:200:ss),x2(i,ss-800:200:ss),'LineWidth',2,'LineStyle','-')
                        end
            
                        plot(x1(i,ss),x2(i,ss),'Marker','o','MarkerSize',10,'MarkerFaceColor',col(i,:))
                    end
            
            
                    %Para registras
                    %Se generan Menu2 y Menu 3 para sobreescribir la tabla
                     %Menu2 es para mostrar en una lista las posiciones de los agentes
                      menu2 = [1:np];
            %          set(app.popupmenu6,'string',menu2)
            %         Menu3 almacenara las posiciones espaciales de cada agente
                      for j=1:np
                      menu3(j,:)=[x1(j,ss) x2(j,ss)];
                      end
                       v=get(app.popupmenu6,'Value');
            
                       set(app.text22,'string',"x = "+(menu3(v,1)+";  "+"y = "+(menu3(v,2))));
            
                    z = get(app.slider10,'Value');
                    zoom_agente = get(app.pushbutton6,'String');
            
            
                    o = get(app.popupmenu3,'Value');% recibe el valor del menu de agente
                    front = get(app.radiobutton11,'Value');
                    back = get(app.radiobutton12,'Value');
            
                    if zoom_agente == "Zoom Personal" & front == 1%% controla la cabeza del agente para realizar zoom de seguimiento
            
                        xlim([x1(o,ss)-z+ejex x1(o,ss)+z+ejex])
                        ylim([x2(o,ss)-z+ejey x2(o,ss)+z+ejey])%realiza el zoom de acercamiento al zoom en el menu desplegable
                        plot(x1(o,ss),x2(o,ss),'*','MarkerSize',12)


                    elseif zoom_agente == "Zoom Personal" & back == 1
                        xlim([x1(o,ss2)-z+ejex x1(o,ss2)+z+ejex])
                        ylim([x2(o,ss2)-z+ejey x2(o,ss2)+z+ejey])
                        plot(x1(o,ss2),x2(o,ss2),'*','MarkerSize',12)
            
            
                    else
                        axis([-z+ejex z+ejex -z+ejey z+ejey]) %realiza el zoom en vista general mediante el slider
            
                    end
                    sss=ss;
                    sss2=ss2;
                    d=0;
                    zz = get(app.slider10,'Value');%% Estas variables son para realizar movimiento de zoom y ejes x e y de manera dinamica
                    ejexx = get(app.ejex,'Value');
                    ejeyy = get(app.ejey,'Value');
            end
            end
                    pause(0.01)
            
            % Menu desplegable para zoom en un agente en especial
            menu = [1:np];
                set(app.popupmenu3,'string',menu)
             end
            set(app.popupmenu6,'Visible','off')
            set(app.text22,'Visible','off')
            end
            cla reset
            end
            
            
            %******** COMIENZO CODIGO 3D*************
            if tres == 1
            flagcolision=0;
            tic
            slider = 101;
            slider2 = 100;
            slider3 = 100;
            slider4 = 100;
            original= get(app.original,'Value');
            deseado= get(app.deseado,'Value');
            
            vectoresAgentes;
            tamanio = size(vectoresAgentes);
            tamanio(1,1);
            
            if original == 1
            
            str1=@(distance,pow)1./((1+distance.^2).^pow);
            
            if distance == 0
            
                str2 = @(distance,pow)0;
            else
            str2 =@(distance,pow) 1./((sigma+distance).^pow);
            
            end
            
            flag=0;
            M=50;
            tf=400; %simulation time
            
            sigma=0;
            K=10;
            beta=0.1;
            alpha=1.1;
            alpha1=10;
            pp=4;
            dt=0.05;
            end
            %OPCION DE PERSONALIZADO
            if deseado == 1
              recibido=get(app.uitable10,'data');
              infl = recibido(12,2);
            
            str1 = @(distance,pow)eval(infl{1});
            
            
            if distance == 0
            
                str4 = @(distance,pow)0;
            else
            str2 = recibido(11,2);
            % str3 = eval(str2{1});
            str4 = @(distance,pow)eval(str2{1});
            
            end
            
            %valores de la tabla
              v1 = recibido(1,2);
              alpha = str2double(v1);
              v2 = recibido(2,2);
              beta = str2double(v2);
              v3 = recibido(3,2);
              sigma = str2double(v3);
              v4 = recibido(4,2);
              K = str2double(v4);
              v5 = recibido(5,2);
              pp = str2double(v5);
              v6 = recibido(6,2);
              flag = str2double(v6);
              v7 = recibido(7,2);
              M = str2double(v7);
              v7 = recibido(8,2);
              tf = str2double(v7);
              v8 = recibido(9,2);
              dt = str2double(v8);
              v9 = recibido(13,2);
              slider = str2double(v9)
              v10 = recibido(14,2);
              slider2 = str2double(v10);
              v11 = recibido(15,2);
              slider3 = str2double(v11);
              v12 = recibido(16,2);
              slider4 = str2double(v12);
            
            end
            
            % set(app.salir,'visible','off');
            % set(app.salir,'value',1);
            set(app.cabeza,'visible','on');
            set(app.cabeza,'String','Con cabeza');
            set(app.cabezano,'String','Sin cabeza');
            set(app.slider2,'MIN',0,'MAX',slider);
            set(app.slider7,'MIN',0,'MAX',slider);
            set(app.slider10,'MIN',1,'MAX',slider4);
            set(app.ejey,'MIN',-slider2,'MAX',slider2);
            set(app.ejex,'MIN',-slider3,'MAX',slider3);
            set(app.slider15,'Value',-38);
            set(app.slider16,'Value',29);


            npp    = get(app.edit1,'String');%Se guarda el vaor que se ingrese en el cuadro de texto en guide en la variable "npp"
            nppp   = str2double(npp); %el valor de npp se convierte en un numer
            
            if nppp <= 1
                set(app.text8,'String','Ingrese valor mayor a 1')
                set (app.pushbutton16, 'BackgroundColor' , 'red' )
            elseif isnan (nppp)
                set(app.text8,'String','Ingrese valor numerico')
                set (app.pushbutton16, 'BackgroundColor' , 'red' )
            
            
            else
            %%%%%%%
            % close all
            % clc
            %function [value,th]=consensus3(gamma0,lambda0)
            set(app.text8,'String','')
            set(app.radiobutton11,'Value',0)
            set(app.slider10,'Value',50)
            set(app.pushbutton5,'String','Play')
            set(app.pushbutton6,'String','Zoom Personal')
            set(app.ejey,'Value',0)
            set(app.ejex,'Value',0)
            set(app.pushbutton9,'visible','on')
            set(app.slider15,'Visible','on');
            set(app.slider16,'Visible','on');
            set(app.text21,'Visible','on');
            movimiento = 0;%Movimiento automatico de los agentes mediante boton
            
            
            np=nppp;%number of agents
            ndim=3;
            ti=0;
             %simulation step
            t=ti:dt:tf;
            nsteps=length(t);
            gamma0=5;
            lambda0=0.3;
            %generate random ic
            for i=1:np
                for kk=1:ndim
                        x00(i,kk)=2*rand-1;
                        v00(i,kk)=2*rand-1;
                end
            end
            vbar0=1/np*sum(v00,1);
            %make initial vbar=0  comment next 3 lines for vbar!=0
            v00(:,1)=-vbar0(1)+v00(:,1);
            v00(:,2)=-vbar0(2)+v00(:,2);
            v00(:,3)=-vbar0(3)+v00(:,3);
            vbar2=1/np*sum(v00,1);
            Gamma=0;
            Lambda=0;
            %scaling of ics for having gamma0 and lambda0
            for i=1:np
                for j=1:np
                    Gamma=Gamma+norm(x00(i,:)-x00(j,:),2).^2;
                    Lambda=Lambda+norm(v00(i,:)-v00(j,:),2).^2;
                end
            end
            
            Gamma=1/(2*np^2)*Gamma;
            Lambda=1/(2*np^2)*Lambda;
            
            x00=sqrt(gamma0/Gamma)*x00;
            v00=sqrt(lambda0/Lambda)*v00;


            x=zeros(2*np,ndim,nsteps);
            % save('z000.mat','x00','v00')
            %load('z000.mat')
            x(:,:,1)=[x00;v00];
            x0=x00;
            v0=v00;
            
            beta2=0;
            distance=zeros(np,np);
            u=zeros(np,ndim,nsteps-1);
            vbar=zeros(1,2);
            i=0;
            p=0.1;
            q=0.9;
            
            Rad=0;
            theta=0; %formation acquisition
            %
            % for ii=1:np-1
            %     theta=theta+norm(x0(ii,:)-x0(ii+1,:)-zdes(ii,:),2).^2;
            % end
            dd=NaN; %collision detection
            for i=1:np
                for j=1:np
                    if i~=j
                        dd=min(norm(x0(i,:)-x0(j,:),2).^2,dd);
                    end
                end
            end
            
            DD(1)=dd;
            % Theta(1)=theta;
            Gamma(1)=gamma0;
            Lambda(1)=lambda0;
            
            VB(1,:)=vbar0;
            zdes=zeros(np-1,ndim);
            
            % FORMATION SHAPES
            % square shape 4 agents
            % zdes=[];
            % zdes(1,:)=[0 5];
            % zdes(2,:)=[5 0];
            % zdes(3,:)=[0 -5];
            % zdes(4,:)=[0 5];
            % zdes(5,:)=[5 0];
            % zdes(6,:)=[0 -5];
            % zdes(7,:)=[0 -5];
            
            % any number of agents - square grid shape
            if cuadrado == 1
            zdes=[];
            ss=2;
            ss1=0.5;
            NN=ceil(sqrt(np)); % ceil redondea al numero entero mas cercano
            kk=1;
            tt=0;
            flagg=1;
            while kk<=np-1
                if kk-tt<=NN
                    zdes(kk,:)=flagg*[0 ss -1];
                    kk=kk+1;
                    if kk-tt==NN&&kk~=np
                        zdes(kk,:)=[ss 0 -2];
                        tt=tt+NN;
                        flagg=-flagg;
                        kk=kk+1;
            
                    end
                end
            end
            end
            % any number of agents - regular poligon with agent at the center
            if poligon == 1;
            zdes=[];
            R=8;
            NN=np-1;
            theta1=2*pi/(NN);
            phi=2*pi*rand(1)/8;
            zdes(1,:)=-[R*cos(phi) R*sin(phi) 0];
            zdes(2,:)=-[R*cos(phi+theta1) R*sin(phi+theta1) 0]+[R*cos(phi) R*sin(phi) 0];
            
            for i=3:NN
                zdes(i,:)=[R*cos(phi+theta1*(i-2)) R*sin(phi+theta1*(i-2)) 0]-[R*cos(phi+theta1*(i-1)) R*sin(phi+theta1*(i-1)) 0]+[0 0 i];
            end
            end
            
            % % bird-like flocking
            if bandada==1
            vbardir=vbar0/norm(vbar0);
            anglevbdir=atan2(vbardir(2),vbardir(1));
            
            for i=1:floor(np/2)
                zdes(i,:)=-[2*cos(anglevbdir-10*2*pi/180) 2*sin(anglevbdir-10*2*pi/180) 0];
            end
            
            for i=floor(np/2)+1:np-1
                zdes(i,:)=[2*cos(anglevbdir+10*2*pi/180) 2*sin(anglevbdir+10*2*pi/180) 0];
            end
            end
            %olympic rings 2d
            if olympic == 1
            Nr = np/5; % agents per ring
            Nr = np/5;
            angle = linspace(0,(Nr-1)*2*pi/Nr,Nr);
            
            xb = cos(angle) * 0.9;
            yb = sin(angle) * 0.9;
            
            xy = cos(angle+0.1) * 0.9 + 1;
            yy = sin(angle+0.1) * 0.9 - 1;
            
            xk = cos(angle+0.2) * 0.9 + 2;
            yk = sin(angle+0.2) * 0.9;
            
            xg = cos(angle+0.1) * 0.9 + 3;
            yg = sin(angle+0.1) * 0.9 - 1;
            
            xr = cos(angle+0.2) * 0.9 + 4;
            yr = sin(angle+0.2) * 0.9;
            zdes=[];
            zdes(1,:)=-[xb(2) yb(2) 0]+[xb(1) yb(1) 0];
            
            for i=2:np/5-1
                zdes(i,:)=[xb(i) yb(i) 0]-[xb(i+1) yb(i+1) 0];
            end
            i=np/5;
            jj=1;
            zdes(i,:)=[xb(i) yb(i) 0]-[xy(jj) yy(jj) 0];
            for i=np/5+1:2*np/5-1
                zdes(i,:)=[xy(jj) yy(jj) 0]-[xy(jj+1) yy(jj+1) 0];
                jj=jj+1;
            end
            i=2*np/5;
            zdes(i,:)=[xy(jj) yy(jj) 0]-[xk(1) yk(1) 0];
            
            jj=1;
            for i=2*np/5+1:3*np/5-1
                zdes(i,:)=[xk(jj) yk(jj) 0]-[xk(jj+1) yk(jj+1) 0];
                jj=jj+1;
            end
            i=3*np/5;
            zdes(i,:)=[xk(jj) yk(jj) 0]-[xr(1) yr(1) 0];
            
            jj=1;
            for i=3*np/5+1:4*np/5-1
                zdes(i,:)=[xr(jj) yr(jj) 0]-[xr(jj+1) yr(jj+1) 0];
                jj=jj+1;
            end
            i=4*np/5;
            zdes(i,:)=[xr(jj) yr(jj) 0]-[xg(1) yg(1) 0];
            
            jj=1;
            for i=4*np/5+1:np-1
                zdes(i,:)=[xg(jj) yg(jj) 0]-[xg(jj+1) yg(jj+1) 0];
                jj=jj+1;
            end
            end
            %cuadrado
            % zdes = [0 2 -1;0 2 -1;2 0 -2; 0 -2 1];
            % cubo
            % zdes = [-2 0 0;0 -2 0;2 0 0; 0 2 -3;-2 0 0;0 -2 0;2 0 0];
            % zdes=[];
            
            if piramide == 1
            % zdes = [0 2 -1;0 2 -1;2 0 -2; 0 -2 1];
            %zdes = [6 0 0;3 -6 0]%triangulo
             zdes = [-3 0 0; 3 -3 0;-3 0 0;1.5 1.5 -5;0.5 0.5 2];
            
            
            % cubo
            %zdes = [-2 0 0;0 -2 0;2 0 0; 0 2 -3;-2 0 0;0 -2 0;2 0 0];
            % zdes=[];
            
            end
            %%Paredes
            if paredes == 1
            conta=1;
            contador=0;
            zdess = 0;
            while conta < np
                if contador == 0
                    zdes(conta,:) = [-2 0 zdess];
                    contador=+1;
                    conta=conta+1;
                elseif contador == 1
                    zdes(conta,:) = [0 -2 zdess];
                    conta=conta+1;
                    contador=contador+1;
                elseif contador == 2
                    zdes(conta,:) = [2 0 zdess];
                    conta=conta+1;
                    contador=contador+1;
                else
                    zdess=+1;
                    zdes(conta,:) = [0 2 zdess];
                    conta=conta+1;
                    contador = 0;
                    zdess = 0;
                end
            pause(0.01)
            end
            end
            %%circulo
            % zdes = [1 0 -0.5 ; 0.5 0 -1 ; -0.5 0 -1 ; -1 0 -0.5  ;-1 0 0.5 ; -0.5 0 1 ; 0.5 0 1];
            
            %%ciculos uno la lado del otro
            if circulo == 1
            conta=1;
            contador=0;
            zdess = 0;
            uno = 1;
            cero5 = 0.5;
            dif = 0;
            while conta < np
                if contador == 0
                    zdes(conta,:) = [uno 0 -cero5];
                    contador=+1;
                    conta=conta+1;
                elseif contador == 1
                    zdes(conta,:) = [cero5 0 -uno];
                    conta=conta+1;
                    contador=contador+1;
                elseif contador == 2
                    zdes(conta,:) = [-cero5 0 -uno];
                    conta=conta+1;
                    contador=contador+1;
                elseif contador == 3
                    zdes(conta,:) = [-uno 0 -cero5];
                    conta=conta+1;
                    contador=contador+1;
                elseif contador == 4
                    zdes(conta,:) = [-uno 0 cero5];
                    conta=conta+1;
                    contador=contador+1;
                elseif contador == 5
                    zdes(conta,:) = [-cero5 0 uno];
                    conta=conta+1;
                    contador=contador+1;
                elseif contador == 6
                    zdes(conta,:) = [cero5 0 uno];
                    conta=conta+1;
                    contador=contador+1;
                else
                    zdess=+1;
                    zdes(conta,:) = [ -3 0 uno];
                    conta=conta+1;
                    contador = 0;
                    zdess = 0;
                    dif = dif +-3;
                end
            pause(0.01)
            end
            
            end
            
            if creativo == 1
                zdes = [vectoresAgentes];
            end
            
            %%
            
            % recibido=get(app.uitable10,'data');
            % stringsolver = recibido(10,2);
            % solver = eval(stringsolver{1});
            
            % if solver == "ode23"
            %%%ODE23%%%%
            
            %         %energy
            %         Ec=zeros(1,length(0:dt:tf));
            %         Ep=zeros(1,length(0:dt:tf));
            %         Ec(1)=Ec0p/2;
            %         Ep(1)=Ep0;
            %         DD=zeros(1,length(0:dt:tf));
            %         DD(1)=D0;
            
            %         %ode23s%%%
            %         xinitt=[x00(:,1);v00(:,1);x00(:,2);v00(:,2)];
            %         [t,X]=ode23s(@csdynmatlab,0:dt:tf,xinitt); % resuelve el sistema de ecuaciones diferenciales con el solver "ode23"
            %         X=X';
            %         ftime=length(t);
            %         x1=X(1:np,:);
            %         x2=X(2*np+1:3*np,:);
            %         v1=X(np+1:2*np,:); % desde np + 1 hasta 2np tenemos la velocidad en el ejex
            %         v2=X(3*np+1:4*np,:);
            %         %%%%%%%
            %end
            
            
            %if solver == "RK4" || solver== "rk4"
            %RK
            for i=1:nsteps-1
            
                %x(:,:,i+1)=RK4(x(:,:,i),u(:,:,i),dt,@cscdyn);
                x(:,:,i+1)=RK4(x(:,:,i),u(:,:,i),dt,@csdynshape);
                x0=squeeze(x(1:np,:,i+1));
                v0=squeeze(x(np+1:end,:,i+1));
            
            %     for i=1:np
            %     distform(i,:)=sqrt(sum((repmat(x0(i,:),np,1)-x0-repmat(zdes,np,1)).^2,2));
            %     end
                gamma=0;
                lambda=0;
                theta=0;
                dd=NaN;
                vbbb=0;
                %lambda gamma theta and dd
                for ii=1:np
                    for jj=1:np
                        gamma=gamma+norm(x0(ii,:)-x0(jj,:),2).^2;
                        lambda=lambda+norm(v0(ii,:)-v0(jj,:),2).^2;
                    end
                    if ii<np
                        %theta=theta+norm(x0(ii,:)-x0(ii+1,:)-zdes,2).^2;
                    end
                    if ii~=jj
                        dd=min(norm(x0(ii,:)-x0(jj,:),2).^2,dd);
                    end
            
                end
               % Theta(i+1)=theta/(np-1);
                Gamma(i+1)=1/(2*np^2)*gamma;
                Lambda(i+1)=1/(2*np^2)*lambda;
                %????
                if dd<=1e-20
                    flagcolision=1;
                end
                DD(i+1)=dd;
                VB(i+1,:)=1/np*sum(v0,1);
                % tests to stop if consensus is likely or unlikely to be achieved (for
                % bench)
            %     if i>=501
            %         test=sum(Lambda(i-150:i));
            %         test2=sum(Theta(i-150:i));
            %         if test<=1||test2>=1000
            %             break
            %             toc
            %         end
            %     end
            
            end
            % end
            
            toc
            
            value=Lambda(end);
            % th=Theta(end);
            
            %end
            flagcolision;
            %figure
            axes(app.axes1)
            %hold on
            tf=dt*i;
            termino=i;
            % for i=1:1
            %    plot(squeeze(x(i,1,:)),squeeze(x(i,2,:)),'LineWidth',2.0,'Color','red')
            % end
            
            N=np;
            col = jet(N);
            hold on
            %trajectories with initial and final points highlighted.
            d = 1;
            ip = 1;
            ipp=1;
            dd = 0;
            sss= 0;
            cont=1;
            view1=19;
            view2=48;
            movim = 1;
            
            %valores filas xx columnas yy dimension zz de la matriz z
            [xx,yy,zz] = size(x);
            xx;
            yy;
            zz;
            
            if zz <= slider
            
            set(app.slider2,'MIN',0,'MAX',zz);
            set(app.slider7,'MIN',0,'MAX',zz);
            
            end
            
              while dd ==0
            
            %Menu2 es para mostrar en una lista las posiciones de los agentes
            menu2 = [1:np];
                set(app.popupmenu6,'string',menu2)
            %Menu3 almacenara las posiciones espaciales de cada agente
            menu3=[];
            
            %el if stop detiene la simulacion en 3D
                stop=get(app.stop,'String');
                if stop == "Stop."
                   dd=1;
                 continue
                end
            
                centrar = get(app.pushbutton10,'String');
                movimiento = get(app.pushbutton5,'String');
            
                if movim == 101
                movimiento = "no";
                set(app.pushbutton5,"String","Reiniciar?");
                Reiniciar = get(app.pushbutton5,'String');
                while Reiniciar == "Reiniciar?"
                Reiniciar = get(app.pushbutton5,'String');
                pause(0.01)
                end


                set(app.slider2,'Value',1)
                movim = 1;
            
                Reinicia = get(app.pushbutton5,'String');
                while Reinicia == "Play"
                Reinicia = get(app.pushbutton5,'String');
                pause(0.01)
                end
            
            
                end
            
            
                %%% AQUI COMIENZA CODIGO PARA GENERAR MOVIMIENTO AUTOMATICO DE LOS AGENTES
                if movimiento == "Ok!"
                set(app.slider2,'Value',movim)
                movim = movim + 1;
                pause(0.1)
                end
            
                view1=get(app.slider15,'Value');
                view11=round(view1);
                view2=get(app.slider16,'Value');
                view22=round(view2);
                s = get(app.slider2,'Value');
                ss = round(s);
                s2=get(app.slider7,'Value');
                ss2=round(s2);
            
                if ss==0
                    ss=1;
                end
                if ss2==0
                    ss2=1;
                end
            pause(0.01)


            %figure
            
            %  a=get(gca,'xlim');b=get(gca,'ylim');c=get(gca,'zlim');
            
             ejex=get(app.ejey,'Value')/10;
             ejey=get(app.ejex,'Value')/10;
             zoom=get(app.slider10,'Value')/10;
             cabeza_agente = get(app.cabeza,'String');
             cabeza_agente2 = get(app.cabezano,'String');
            
              grid on
            
            
            %trajectories with initial and final points highlighted.
            cla
            hold on
            
              if centrar == "Centrado"
                        set(app.ejex,'Value',0)
                        set(app.ejey,'Value',0)
                        set(app.slider10,'Value',50)/10
                        set(app.slider15,'Value',-38);
                        set(app.slider16,'Value',29);
                        set(app.pushbutton10,'String',"Centrar")
                end
            
            if cabeza_agente == "Con cabeza"
            set(app.cabeza,'visible','on');
            set(app.cabezano,'visible','off');
            set (app.pushbutton16, 'BackgroundColor' , 'green' )
            a=[-zoom+ejey zoom+ejey];b=[-zoom zoom];c=[-zoom+ejex zoom+ejex];
                 axis([a b c])
            for kk=1:np
            
              %comment for removing trajectories
              zoom_agente= get(app.pushbutton6,'String');
              o = get(app.popupmenu3,'Value');% recibe el valor del menu de agente
              front = get(app.radiobutton11,'Value');
              back = get(app.radiobutton12,'Value');
              estela = get(app.estela,'Value');
                     if estela == 0
                         plot3(squeeze(x(kk,1,ss2:1:ss)),squeeze(x(kk,2,ss2:1:ss)),squeeze(x(kk,3,ss2:1:ss)),'Color',col(kk,:),'LineWidth',2','LineStyle','-')
                   plot3(squeeze(x(kk,1,ss)),squeeze(x(kk,2,ss)),squeeze(x(kk,3,ss)),'Color',col(kk,:),'Marker','o')
                     end
            
                    if estela == 1
                        if ss > 100
                              plot3(squeeze(x(kk,1,ss-100:50:ss)),squeeze(x(kk,2,ss-100:50:ss)),squeeze(x(kk,3,ss-100:50:ss)),'Color',col(kk,:),'LineWidth',2','LineStyle','-')
                        else
            
                            if ss<20
                                plot3(squeeze(x(kk,1,ss2:1:ss)),squeeze(x(kk,2,ss2:1:ss)),squeeze(x(kk,3,ss2:1:ss)),'Color',col(kk,:),'LineWidth',2','LineStyle','-')
                            end
                            if ss > 20
                                plot3(squeeze(x(kk,1,ss-20:10:ss)),squeeze(x(kk,2,ss-20:10:ss)),squeeze(x(kk,3,ss-20:10:ss)),'Color',col(kk,:),'LineWidth',2','LineStyle','-')
                            end
                        end
            
            
                    end
            
            
                menu3(kk,:)=[x(kk,1,ss) x(kk,2,ss) x(kk,3,ss)];
                v=get(app.popupmenu6,'Value');
                set(app.text22,'string',"x = "+(menu3(v,1))+";  "+"y = "+(menu3(v,2))+";  "+"z = "+(menu3(v,3)));
            
            if zoom_agente == "Zoom Personal" & front == 1%% controla la cabeza del agente para realizar zoom de seguimiento
            
            %       a=[-zoom+ejey zoom+ejey];b=[-zoom zoom];c=[-zoom+ejex zoom+ejex];
            %       axis([a b c])
            %       xlim([x(o,1,ss)-zoom+ejex x(o,1,ss)+zoom+ejex])
            %       ylim([x(o,2,ss)-zoom+ejey x(o,2,ss)+zoom+ejey])
            %       zlim([x(o,3,ss)-zoom+ejey x(o,3,ss)+zoom+ejey])
            
            %      a=[x(o,ss)-zoom+ejey x(o,ss)+zoom+ejey];b=[x(o,ss)-zoom x(o,ss)+zoom];c=[x(o,ss)-zoom+ejex x(o,ss)+zoom+ejex];
                 a=[x(o,2,ss)-zoom+ejey x(o,2,ss)+zoom+ejey];b=[-zoom+x(o,3,ss) x(o,3,ss)+zoom];c=[x(o,1,ss)-zoom+ejex x(o,1,ss)+zoom+ejex];
            % a
            % x(o,1,ss)
            % b
            % x(o,1,ss)
            %  x(o,2,ss)
            %  x(o,3,ss)
            
            
            %      a1=[x(o,2,ss)-zoom+ejey x(o,2,ss)+zoom+ejey];b1=[x(o,2,ss)-zoom x(o,2,ss)+zoom];c1=[x(o,2,ss)-zoom+ejex x(o,2,ss)+zoom+ejex];
            %      a2=[x(o,3,ss)-zoom+ejey x(o,3,ss)+zoom+ejey];b2=[x(o,3,ss)-zoom x(o,3,ss)+zoom];c2=[x(o,3,ss)-zoom+ejex x(o,3,ss)+zoom+ejex];
                 axis([c a b])
            %      axis([a1 b1 c1])
            %      axis([a2 b2 c2])
            %      plot3(squeeze(x(o,1,ss)),squeeze(x(o,2,ss)),squeeze(x(o,3,ss)))
            
            elseif zoom_agente == "Zoom Personal" & back == 1
            %           set(app.salir,'visible','on');
            %           set(app.salir,'value',0);
                   a=[x(o,ss)-zoom+ejey zoom+ejey];b=[x(o,ss)-zoom zoom];c=[x(o,ss)-zoom+ejex zoom+ejex];
                   axis([a b c])
                   plot3(squeeze(x(o,1,ss2)),squeeze(x(o,2,ss2)),squeeze(x(o,3,ss2)))
            
            
              else
             salirzoom=get(app.salirzoom,'String');
             if salirzoom == "Salir zoom"
              a=[-zoom+ejey zoom+ejey];b=[-zoom zoom];c=[-zoom+ejex zoom+ejex];
              axis([a b c])
             end
              end
            
            end
            end
            if cabeza_agente == "ok"
            set(app.cabeza,'visible','off');
            set(app.cabezano,'visible','on');
            % Menu2 es para mostrar en una lista las posiciones de los agentes
            % menu2 = [1:np]
            %     set(app.popupmenu6,'string',menu2)
            % Menu3 almacenara las posiciones espaciales de cada agente
            % menu3=[];
            
            
            a=[-zoom+ejey zoom+ejey];b=[-zoom zoom];c=[-zoom+ejex zoom+ejex];
              axis([a b c])
            
             for  kk=1:np
            
            
              if salirzoom == "Salir zoom"
                a=[-zoom+ejey zoom+ejey];b=[-zoom zoom];c=[-zoom+ejex zoom+ejex];
                axis([a b c])
              end
              if zoom_agente == "Zoom Personal" & front == 1
                a=[x(o,2,ss)-zoom+ejey x(o,2,ss)+zoom+ejey];b=[-zoom +zoom];c=[x(o,1,ss)-zoom+ejex x(o,1,ss)+zoom+ejex];
               axis([a b c])
            
              end
            
            %   if zoom_agente == "Zoom Personal" & front == 1%% controla la cabeza del agente para realizar zoom de seguimiento
            %   a=[x(o,ss)-zoom+ejey x(o,ss)+zoom+ejey];b=[x(o,ss)-zoom x(o,ss)+zoom];c=[x(o,ss)-zoom+ejex x(o,ss)+zoom+ejex];
            %   axis([a b c])
            %   end
            %
                    if estela == 1
                        if ss > 100
                            plot3(squeeze(x(kk,1,ss-100:50:ss)),squeeze(x(kk,2,ss-100:50:ss)),squeeze(x(kk,3,ss-100:50:ss)),'Color',col(kk,:),'LineWidth',2','LineStyle','-')
                        else
            
                            if ss<20
                                plot3(squeeze(x(kk,1,ss2:1:ss)),squeeze(x(kk,2,ss2:1:ss)),squeeze(x(kk,3,ss2:1:ss)),'Color',col(kk,:),'LineWidth',2','LineStyle','-')
                            end
                            if ss > 20
            
                                plot3(squeeze(x(kk,1,ss-20:1:ss)),squeeze(x(kk,2,ss-20:1:ss)),squeeze(x(kk,3,ss-20:1:ss)),'Color',col(kk,:),'LineWidth',2','LineStyle','-')
                            end
            
                        end
            
            %             plot(x1(i,ss),x2(i,ss),'Marker','o','MarkerSize',10,'MarkerFaceColor',col(i,:))
                    end
            
              plot3(squeeze(x(kk,1,ss)),squeeze(x(kk,2,ss)),squeeze(x(kk,3,ss)),'Marker','o','MarkerSize',10,'MarkerFaceColor',col(kk,:))
            
            %   if zoom_agente == "Zoom Personal" & front == 1%% controla la cabeza del agente para realizar zoom de seguimiento
            
            %         a=[x(o,ss)-zoom+ejey x(o,ss)+zoom+ejey];b=[x(o,ss)-zoom x(o,ss)+zoom];c=[x(o,ss)-zoom+ejex x(o,ss)+zoom+ejex];
            %       a1=[x(o,2,ss)-zoom+ejey x(o,2,ss)+zoom+ejey];b1=[x(o,2,ss)-zoom x(o,2,ss)+zoom];c1=[x(o,2,ss)-zoom+ejex x(o,2,ss)+zoom+ejex];
            %       a2=[x(o,3,ss)-zoom+ejey x(o,3,ss)+zoom+ejey];b2=[x(o,3,ss)-zoom x(o,3,ss)+zoom];c2=[x(o,3,ss)-zoom+ejex x(o,3,ss)+zoom+ejex];
            %         axis([a b c])


            %         if zoom_agente == "Zoom Personal" & front == 1%% controla la cabeza del agente para realizar zoom de seguimiento
            
            %       a=[-zoom+ejey zoom+ejey];b=[-zoom zoom];c=[-zoom+ejex zoom+ejex];
            %       axis([a b c])
            %       xlim([x(o,1,ss)-zoom+ejex x(o,1,ss)+zoom+ejex])
            %       ylim([x(o,2,ss)-zoom+ejey x(o,2,ss)+zoom+ejey])
            %       zlim([x(o,3,ss)-zoom+ejey x(o,3,ss)+zoom+ejey])
            
            %      a=[x(o,ss)-zoom+ejey x(o,ss)+zoom+ejey];b=[x(o,ss)-zoom x(o,ss)+zoom];c=[x(o,ss)-zoom+ejex x(o,ss)+zoom+ejex];
            %      a1=[x(o,2,ss)-zoom+ejey x(o,2,ss)+zoom+ejey];b1=[x(o,2,ss)-zoom x(o,2,ss)+zoom];c1=[x(o,2,ss)-zoom+ejex x(o,2,ss)+zoom+ejex];
            %      a2=[x(o,3,ss)-zoom+ejey x(o,3,ss)+zoom+ejey];b2=[x(o,3,ss)-zoom x(o,3,ss)+zoom];c2=[x(o,3,ss)-zoom+ejex x(o,3,ss)+zoom+ejex];
            %      axis([a b c])
            %      axis([a1 b1 c1])
            %      axis([a2 b2 c2])
            %      plot3(squeeze(x(o,1,ss)),squeeze(x(o,2,ss)),squeeze(x(o,3,ss)))


            %   end
            
            menu3(kk,:)=[x(kk,1,ss) x(kk,2,ss) x(kk,3,ss)];
             end
            v=get(app.popupmenu6,'Value');
            set(app.text22,'string',"x = "+(menu3(v,1))+";  "+"y = "+(menu3(v,2))+";  "+"z = "+(menu3(v,3)));
            
            end
            pause(0.01)


              view(-view11,view22)
             %   view(-5,10)
            %
            %
            % axis([a b c])
            sss=ss;
            % d=0;
            view2=view1;
            %end
            menu = [1:np];
                set(app.popupmenu3,'string',menu)
            end
            set(app.popupmenu6,'Visible','off')
            set(app.text22,'Visible','off')
            % end
            cla reset
            end
            %******** FIN CODIGO 3D *****************
            end
            end
        end

        % Button pushed function: pushbutton5
        function pushbutton5_Callback(app, event)
            Reiniciarr = get(app.pushbutton5,'String');
            if Reiniciarr == "Reiniciar?"
            set(app.pushbutton5,'String',"Play?")
            elseif Reiniciarr == "Play?"
            set(app.pushbutton5,'String',"Ok!")
            else
            set(app.pushbutton5,'String',"Ok!")
            end
        end

        % Button pushed function: pushbutton6
        function pushbutton6_Callback(app, event)
            set(app.pushbutton6,'String',"Zoom Personal")
            set(app.popupmenu3,'visible','on')
            set(app.text13,'visible','on')
            set(app.uibuttongroup3,'visible','on')
            set(app.radiobutton11,'Value',1)
            set(app.pushbutton6,'visible','off')
            set(app.salirzoom,'visible','on')
        end

        % Button pushed function: pushbutton9
        function pushbutton9_Callback(app, event)
            % hObject    handle to pushbutton9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global np ndim sigma K beta pp flag alpha M zdes tf
            f = uifigure('Position', [100 100 300 265],'Color','White','Name','Obtención de datos');
                t = uitable(f,'Data',[np;ndim;alpha;beta;flag;M;sigma;K;pp;tf]);
                t.FontSize = 10;%alpha beta flag M sigma K pp   ;'Alpha' ;'Beta' ; 'Flag';'M' ;'Sigma' ;'K' ;'PP'
                t.ColumnName = {'Datos'};
                t.RowName = {'Agentes' 'Dimensión' 'Alpha' 'Beta' 'Flag' 'M' 'Sigma' 'K' 'PP' 'Tiempo final'};
                t.Position = [50 50 195 205];
        end

        % Button pushed function: salirzoom
        function salirzoom_Callback(app, event)
            set(app.salirzoom,'String','Salir zoom')
            set(app.pushbutton6,'String','Zoom personal')
            set(app.popupmenu3,'visible','off')
            set(app.text13,'visible','off')
            set(app.uibuttongroup3,'visible','off')
            set(app.radiobutton11,'Value',0)
            set(app.pushbutton6,'visible','on')
            set(app.salirzoom,'visible','off')
        end

        % Button pushed function: stop
        function stop_Callback(app, event)
            global cuadrado bandada poligon vector vectoresAgentes;
            vectoresAgentes = [];
            vector = 1;
            
            set(app.text28,'Visible','off')
            set(app.edit12,'Visible','off')
            set(app.edit13,'Visible','off')
            set(app.edit14,'Visible','off')
            set(app.text25,'Visible','off')
            set(app.text26,'Visible','off')
            set(app.text27,'Visible','off')
            set(app.text13,'visible','off')
            set(app.cabeza,'visible','off');
            set(app.cabezano,'visible','off');
            set(app.pushbutton6,'visible','on');
            set(app.uitable10,'Visible','off')
            set(app.pushbutton17,'Visible','off')
            set(app.slider15,'Visible','off');
            set(app.slider16,'Visible','off');
            set(app.slider16,'Visible','off');
            set(app.salirzoom,'Visible','off');
            set(app.pushbutton9,'visible','off')
            set(app.uibuttongroup3,'visible','off')
            % set(app.salir,'visible','off');
            set(app.popupmenu3,'visible','off')
            
            
            set(app.edit12,'String',' ')
            set(app.edit13,'String',' ')
            set(app.edit14,'String',' ')
            set(app.stop,'String','Stop.')
            set(app.cabeza,'String','Cabeza');
            set(app.popupmenu6,'String','Agent position')
            set(app.text8,'String','Ingrese nuevos valores')
            set(app.pushbutton6,'String',"Stop")
            set(app.pushbutton5,'String',"Stop")
            set(app.salirzoom,'String','Stop')
            
            set(app.original,'Value',1);
            set(app.slider2,'Value',0);
            set(app.slider7,'Value',0);
            set(app.pushbutton6,'Value',0)
            set(app.pushbutton5,'Value',0)
            
            set (app.pushbutton16, 'BackgroundColor' , 'red' )
            
            
            cla reset
        end

        % Menu selected function: universidad
        function universidad_Callback(app, event)
            web https://www.utem.cl/ -browser
        end

        % Cell edit callback: uitable10
        function uitable10CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'Slider';
            app.archivo = uimenu(app.figure1);
            app.archivo.Text = 'Archivo';
            app.grabar = uimenu(app.archivo);
            app.grabar.Text = 'Grabar';
            app.natural = uimenu(app.grabar);
            app.natural.Text = 'Natural';
            app.personalizado = uimenu(app.grabar);
            app.personalizado.MenuSelectedFcn = createCallbackFcn(app, @personalizado_Callback, true);
            app.personalizado.Text = 'Personalizado';
            app.Untitled_1 = uimenu(app.figure1);
            app.Untitled_1.Text = 'Herramientas';
            app.Untitled_2 = uimenu(app.figure1);
            app.Untitled_2.Text = 'Información';
            app.nosotros = uimenu(app.Untitled_2);
            app.nosotros.MenuSelectedFcn = createCallbackFcn(app, @nosotros_Callback, true);
            app.nosotros.Text = 'Nosotros';
            app.universidad = uimenu(app.Untitled_2);
            app.universidad.MenuSelectedFcn = createCallbackFcn(app, @universidad_Callback, true);
            app.universidad.Text = 'Universidad';
            app.uipanel1 = uipanel(app.figure1);
            app.axes1 = uiaxes(app.uipanel1);
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = 'Run';
            app.stop = uibutton(app.uipanel1, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stop_Callback, true);
            app.stop.Text = 'Stop';
            app.edit1 = uieditfield(app.uipanel1, 'text');
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'N°  Agents';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Front Control';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Text = 'Back Control';
            app.slider2 = uislider(app.uipanel1);
            app.slider2.Limits = [0 4000];
            app.slider2.MajorTicks = [];
            app.slider2.MinorTicks = [];
            app.text8 = uilabel(app.uipanel1);
            app.text8.Text = '';
            app.slider7 = uislider(app.uipanel1);
            app.slider7.Limits = [0 10000];
            app.slider7.MajorTicks = [];
            app.slider7.MinorTicks = [];
            app.text11 = uilabel(app.uipanel1);
            app.text11.Text = 'Zoom Control';
            app.popupmenu3 = uidropdown(app.uipanel1);
            app.popupmenu3.Items = {'Pop-up Menu'};
            app.popupmenu3.ValueChangedFcn = createCallbackFcn(app, @popupmenu3_Callback, true);
            app.popupmenu3.Visible = 'off';
            app.popupmenu3.Value = 'Pop-up Menu';
            app.slider10 = uislider(app.uipanel1);
            app.slider10.Limits = [1 100];
            app.slider10.MajorTicks = [];
            app.slider10.MinorTicks = [];
            app.slider10.Value = 1;
            app.pushbutton5 = uibutton(app.uipanel1, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5_Callback, true);
            app.pushbutton5.Text = 'Play';
            app.pushbutton6 = uibutton(app.uipanel1, 'push');
            app.pushbutton6.ButtonPushedFcn = createCallbackFcn(app, @pushbutton6_Callback, true);
            app.pushbutton6.Text = 'Zoom personal';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Visible = 'off';
            app.text13.Text = 'Agent';
            app.ejey = uislider(app.uipanel1);
            app.ejey.Limits = [-100 100];
            app.ejey.MajorTicks = [];
            app.ejey.MinorTicks = [];
            app.ejex = uislider(app.uipanel1);
            app.ejex.Limits = [-100 100];
            app.ejex.MajorTicks = [];
            app.ejex.MinorTicks = [];
            app.text14 = uilabel(app.uipanel1);
            app.text14.Text = '-';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Text = '+';
            app.uibuttongroup3 = uibuttongroup(app.uipanel1);
            app.uibuttongroup3.Visible = 'off';
            app.radiobutton12 = uiradiobutton(app.uibuttongroup3);
            app.radiobutton12.Text = 'Back';
            app.radiobutton11 = uiradiobutton(app.uibuttongroup3);
            app.radiobutton11.Text = 'Front';
            app.radiobutton11.Value = true;
            app.radiobutton23 = uiradiobutton(app.uibuttongroup3);
            app.pushbutton9 = uibutton(app.uipanel1, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton9_Callback, true);
            app.pushbutton9.Visible = 'off';
            app.pushbutton9.Text = 'Get information';
            app.pushbutton10 = uibutton(app.uipanel1, 'push');
            app.pushbutton10.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10_Callback, true);
            app.pushbutton10.Text = 'Centrar';
            app.uitable10 = uitable(app.uipanel1);
            app.uitable10.ColumnName = {'Variables'; 'Value'};
            app.uitable10.RowName = '';
            app.uitable10.ColumnEditable = [false true];
            app.uitable10.CellEditCallback = createCallbackFcn(app, @uitable10CellEdit, true);
            app.uitable10.Visible = 'off';
            app.uibuttongroup4 = uibuttongroup(app.uipanel1);
            app.uibuttongroup4.Title = 'Configuración variables';
            app.original = uiradiobutton(app.uibuttongroup4);
            app.original.Text = 'Original';
            app.original.Value = true;
            app.deseado = uiradiobutton(app.uibuttongroup4);
            app.deseado.Text = 'Personalizado';
            app.slider15 = uislider(app.uipanel1);
            app.slider15.Limits = [-100 100];
            app.slider15.MajorTicks = [];
            app.slider15.MinorTicks = [];
            app.slider15.Visible = 'off';
            app.slider16 = uislider(app.uipanel1);
            app.slider16.Limits = [-100 100];
            app.slider16.MajorTicks = [];
            app.slider16.MinorTicks = [];
            app.slider16.Visible = 'off';
            app.text21 = uilabel(app.uipanel1);
            app.text21.Visible = 'off';
            app.text21.Text = 'Rotary Camera';
            app.cabezano = uibutton(app.uipanel1, 'push');
            app.cabezano.ButtonPushedFcn = createCallbackFcn(app, @cabezano_Callback, true);
            app.cabezano.Visible = 'off';
            app.cabezano.Text = 'Sin cabeza';
            app.popupmenu6 = uidropdown(app.uipanel1);
            app.popupmenu6.Items = {' Agent Position'};
            app.popupmenu6.Value = ' Agent Position';
            app.text22 = uilabel(app.uipanel1);
            app.text22.Text = '';
            app.pushbutton16 = uibutton(app.uipanel1, 'push');
            app.pushbutton16.Text = '';
            app.uibuttongroup6 = uibuttongroup(app.uipanel1);
            app.radiobutton26 = uiradiobutton(app.uibuttongroup6);
            app.radiobutton26.Text = '2D';
            app.radiobutton26.Value = true;
            app.radiobutton27 = uiradiobutton(app.uibuttongroup6);
            app.radiobutton27.Text = '3D';
            app.popupmenu4 = uidropdown(app.uibuttongroup6);
            app.popupmenu4.Items = {'Default', 'Square', 'Birds-Like Flocking', 'Poligon', 'Circle', 'Triangulo', 'Creativo', ''};
            app.popupmenu4.ValueChangedFcn = createCallbackFcn(app, @popupmenu4_Callback, true);
            app.popupmenu4.Value = 'Default';
            app.popupmenu7 = uidropdown(app.uibuttongroup6);
            app.popupmenu7.Items = {'Default', 'Square', 'wall', 'circle', 'Piramide', 'Bandada', 'Poligono', 'Olympic', 'Creativo'};
            app.popupmenu7.ValueChangedFcn = createCallbackFcn(app, @popupmenu7_Callback, true);
            app.popupmenu7.Visible = 'off';
            app.popupmenu7.Value = 'Default';
            app.pushbutton17 = uibutton(app.uipanel1, 'push');
            app.pushbutton17.ButtonPushedFcn = createCallbackFcn(app, @pushbutton17_Callback, true);
            app.pushbutton17.Visible = 'off';
            app.pushbutton17.Text = '+';
            app.edit12 = uieditfield(app.uipanel1, 'text');
            app.edit12.Visible = 'off';
            app.edit12.Value = 'x';
            app.text25 = uilabel(app.uipanel1);
            app.text25.Text = '';
            app.edit13 = uieditfield(app.uipanel1, 'text');
            app.edit13.Visible = 'off';
            app.edit13.Value = 'y';
            app.edit14 = uieditfield(app.uipanel1, 'text');
            app.edit14.Visible = 'off';
            app.edit14.Value = 'z';
            app.text26 = uilabel(app.uipanel1);
            app.text26.Text = '';
            app.text27 = uilabel(app.uipanel1);
            app.text27.Text = '';
            app.text28 = uilabel(app.uipanel1);
            app.text28.Visible = 'off';
            app.text28.Text = 'Ingrese Vectores';
            app.salirzoom = uibutton(app.uipanel1, 'push');
            app.salirzoom.ButtonPushedFcn = createCallbackFcn(app, @salirzoom_Callback, true);
            app.salirzoom.Visible = 'off';
            app.salirzoom.Text = 'Salir Zoom';
            app.cabeza = uibutton(app.uipanel1, 'push');
            app.cabeza.ButtonPushedFcn = createCallbackFcn(app, @cabeza_Callback, true);
            app.cabeza.Visible = 'off';
            app.cabeza.Text = 'Con cabeza';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Slider_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Slider_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end